classdef saveDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        saveOptionUIFigure           matlab.ui.Figure
        GridLayout4                  matlab.ui.container.GridLayout
        TablePanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        OptimisationSummaryCheckBox  matlab.ui.control.CheckBox
        SaveButton                   matlab.ui.control.Button
        SelectFilePathPanel          matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        FolderPathTextArea           matlab.ui.control.TextArea
        FolderPathTextAreaLabel      matlab.ui.control.Label
        SelectButton                 matlab.ui.control.Button
        SaveoptionsLabel             matlab.ui.control.Label
        FigurePanel                  matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        DropDown_4                   matlab.ui.control.DropDown
        DropDown_3                   matlab.ui.control.DropDown
        DropDown_2                   matlab.ui.control.DropDown
        ExcitabilitypvalueCheckBox   matlab.ui.control.CheckBox
        ExcitabilityLevelCheckBox    matlab.ui.control.CheckBox
        LogarithmicmaximumlikelihoodcurveCheckBox  matlab.ui.control.CheckBox
    end

    % Author: Ke Ma, Stephan M. Goetz; @10/12/2023
    % MATLAB version: R2022b
    %
    % This code is available for private and academic use, provided that any
    % resulting publications, presentations, or academic works citing
    % the use of the Software include an appropriate citation
    % acknowledging the Software and its authors.
    %
    % Users must contact us to obtain a separate agreement before using
    % the Software for commercial purposes. Commercial use includes,
    % but is not limited to, incorporating the Software into a product
    % for sale or distribution, or using the Software to provide services
    % or support for a commercial entity.
    %
    % Email: km834@cam.ac.uk, smg84@cam.ac.uk
    %
    properties (Access = private)
        mainCallerApp % main app
    end

    properties (Access = private)
        selectedFolderPath % select folder
        saveOperationClass % save operation class
    end

    methods (Access = private)
        %% save figrues function
        function saveSelectedFigures(app)
            if app.mainCallerApp.finishedFlag == true
                % get the folder path
                folderPath = app.selectedFolderPath;

                % save figures
                % save Figure 1: variability MEP IO curve
                if app.LogarithmicmaximumlikelihoodcurveCheckBox.Value
                    fileName = ['Figure_Logarithmic_maximum_likelihood_curve', app.DropDown_2.Value];
                    filePath = fullfile(folderPath, fileName);
                    app.saveOperationClass.checkFileandSave(app.mainCallerApp.UIAxes_VariabilityMEPCurve, filePath);
                end

                % save Figure 2: excitability level
                if app.ExcitabilityLevelCheckBox.Value
                    fileName = ['Figure_Excitability_level', app.DropDown_3.Value];
                    filePath = fullfile(folderPath, fileName);
                    app.saveOperationClass.checkFileandSave(app.mainCallerApp.UIAxes_excitabilityLevel, filePath);
                end

                % save Figure 3: excitability level p-value
                if app.ExcitabilitypvalueCheckBox.Value
                    fileName = ['Figure_Excitability_pvalue', app.DropDown_4.Value];
                    filePath = fullfile(folderPath, fileName);
                    app.saveOperationClass.checkFileandSave(app.mainCallerApp.UIAxes_excitabilityPvalue, filePath);
                end
            else
                message = 'No results to be saved!';
                uialert(app.saveOptionUIFigure, message, 'Warning', 'Icon', 'warning');
            end
        end

        %% save table functions
        function saveSelectedTables(app)
            if app.mainCallerApp.finishedFlag == true
                % get file path
                folderPath = app.selectedFolderPath;
                %
                myOptimisedTable = app.mainCallerApp.optimisedTable;

                % save tables
                % optimised curve model parameters
                if app.OptimisationSummaryCheckBox.Value
                    fileName = ['Table_Optimisation_Summary', '.xlsx'];
                    filePath = fullfile(folderPath, fileName);
                    app.saveOperationClass.checkFileandSave(myOptimisedTable, filePath);
                end
            else
                message = 'No results to be saved!';
                uialert(app.saveOptionUIFigure, message, 'Warning', 'Icon', 'warning');
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % find current working path
            functionPath = fullfile(pwd, 'Functions');
            addpath(functionPath)
            %
            app.mainCallerApp = mainApp; % store the main app

            % initialise the class
            app.saveOperationClass = saveFileOperation();

            % update all drop-down lists
            app.DropDown_2.Items = app.saveOperationClass.figureFormat;
            app.DropDown_3.Items = app.saveOperationClass.figureFormat;
            app.DropDown_4.Items = app.saveOperationClass.figureFormat;

            % update current directory
            app.FolderPathTextArea.Value = pwd;

            % save main app as a pdf
            % app.saveOperationClass.exportApptoPDF(app.mainCallerApp.MEPIOCurveAnalyserUIFigure);

        end

        % Button pushed function: SelectButton
        function selectFilepath(app, event)
            FolderPath = uigetdir('', 'Select a folder');

            if not(isequal(FolderPath, 0)) % a folder path is selected
                app.selectedFolderPath = FolderPath;
                app.FolderPathTextArea.Value = FolderPath;
            end

        end

        % Button pushed function: SaveButton
        function saveOperation(app, event)
            % save figures
            saveSelectedFigures(app);
            % save tables
            saveSelectedTables(app);

        end

        % Close request function: saveOptionUIFigure
        function saveOptionUIFigureCloseRequest(app, event)
            % Enable the Save Option in main app
            app.mainCallerApp.SaveResultsButton.Enable = "on";

            % Delete the dialog box
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.saveOptionUIFigure = uifigure('Visible', 'off');
            app.saveOptionUIFigure.Name = 'MATLAB App';
            app.saveOptionUIFigure.CloseRequestFcn = createCallbackFcn(app, @saveOptionUIFigureCloseRequest, true);
            app.GridLayout4 = uigridlayout(app.saveOptionUIFigure);
            app.FigurePanel = uipanel(app.GridLayout4);
            app.FigurePanel.Title = 'Figure:';
            app.GridLayout = uigridlayout(app.FigurePanel);
            app.LogarithmicmaximumlikelihoodcurveCheckBox = uicheckbox(app.GridLayout);
            app.LogarithmicmaximumlikelihoodcurveCheckBox.Text = 'Logarithmic maximum-likelihood curve';
            app.LogarithmicmaximumlikelihoodcurveCheckBox.Value = true;
            app.ExcitabilityLevelCheckBox = uicheckbox(app.GridLayout);
            app.ExcitabilityLevelCheckBox.Text = 'Excitability Level';
            app.ExcitabilityLevelCheckBox.Value = true;
            app.ExcitabilitypvalueCheckBox = uicheckbox(app.GridLayout);
            app.ExcitabilitypvalueCheckBox.Text = 'Excitability p value';
            app.ExcitabilitypvalueCheckBox.Value = true;
            app.DropDown_2 = uidropdown(app.GridLayout);
            app.DropDown_3 = uidropdown(app.GridLayout);
            app.DropDown_4 = uidropdown(app.GridLayout);
            app.SaveoptionsLabel = uilabel(app.GridLayout4);
            app.SaveoptionsLabel.Text = 'Save options:';
            app.SelectFilePathPanel = uipanel(app.GridLayout4);
            app.SelectFilePathPanel.Title = 'Select File Path';
            app.GridLayout3 = uigridlayout(app.SelectFilePathPanel);
            app.SelectButton = uibutton(app.GridLayout3, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @selectFilepath, true);
            app.SelectButton.Tooltip = {'Please select a file.'};
            app.SelectButton.Text = 'Select';
            app.FolderPathTextAreaLabel = uilabel(app.GridLayout3);
            app.FolderPathTextAreaLabel.Text = 'Folder Path:';
            app.FolderPathTextArea = uitextarea(app.GridLayout3);
            app.FolderPathTextArea.Editable = 'off';
            app.FolderPathTextArea.Value = {'None'};
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveOperation, true);
            app.SaveButton.Text = 'Save';
            app.TablePanel = uipanel(app.GridLayout4);
            app.TablePanel.Title = 'Table:';
            app.GridLayout2 = uigridlayout(app.TablePanel);
            app.OptimisationSummaryCheckBox = uicheckbox(app.GridLayout2);
            app.OptimisationSummaryCheckBox.Text = 'Optimisation Summary';
            app.OptimisationSummaryCheckBox.Value = true;
            % Show the figure after all components are created
            app.saveOptionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saveDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.saveOptionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.saveOptionUIFigure)
        end
    end
end