classdef MEP_IO_Model_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MEPIOCurveAnalyserUIFigure  matlab.ui.Figure
        InformationMenu             matlab.ui.container.Menu
        AboutusMenu                 matlab.ui.container.Menu
        GridLayout4                 matlab.ui.container.GridLayout
        ModelSelectionPanel         matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        RunButton                   matlab.ui.control.Button
        stopTolerance               matlab.ui.control.NumericEditField
        stopTolerance_Label         matlab.ui.control.Label
        MaxFunEvalsEditField        matlab.ui.control.NumericEditField
        MaxFunEvalsEditField_Label  matlab.ui.control.Label
        TolFunEditField             matlab.ui.control.NumericEditField
        FunctionTolerance_Label     matlab.ui.control.Label
        MaxIterEditField            matlab.ui.control.NumericEditField
        MaximumIteration_Label      matlab.ui.control.Label
        OptimisationMethodDropDown  matlab.ui.control.DropDown
        OptimisationMethodDropDownLabel  matlab.ui.control.Label
        ModelFormTextArea           matlab.ui.control.TextArea
        ModelFormTextAreaLabel      matlab.ui.control.Label
        VyvariabilityalongyaxisMEPCheckBox  matlab.ui.control.CheckBox
        VxvariabilityalongxaxisStimulusStrengthCheckBox  matlab.ui.control.CheckBox
        VariabilityParameterSelectionLabel  matlab.ui.control.Label
        CurveModelDropDown          matlab.ui.control.DropDown
        CurveModelDropDownLabel     matlab.ui.control.Label
        ResultsPanel                matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        optiParameterTable          matlab.ui.control.Table
        SaveResultsButton           matlab.ui.control.Button
        UIAxes_excitabilityPvalue   matlab.ui.control.UIAxes
        UIAxes_excitabilityLevel    matlab.ui.control.UIAxes
        UIAxes_VariabilityMEPCurve  matlab.ui.control.UIAxes
        FileSelectionPanel          matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        LoadButton                  matlab.ui.control.Button
        YaxisDropDown               matlab.ui.control.DropDown
        YaxisDropDownLabel          matlab.ui.control.Label
        NormaliseXaxisdependingonthemaximumxCheckBox  matlab.ui.control.CheckBox
        MaxXaxisEditField           matlab.ui.control.NumericEditField
        MaxXaxisEditFieldLabel      matlab.ui.control.Label
        XaxisDropDown               matlab.ui.control.DropDown
        XaxisDropDownLabel          matlab.ui.control.Label
        SelectButton                matlab.ui.control.Button
        FilePathTextArea            matlab.ui.control.TextArea
        FilePathTextAreaLabel       matlab.ui.control.Label
    end

    % Author: Ke Ma, Stephan M. Goetz; @10/12/2023
    % MATLAB version: R2022b
    %
    % This softeware is available for private and academic use, provided that any
    % resulting publications, presentations, or academic works citing
    % the use of the Software include an appropriate citation
    % acknowledging the Software and its authors.
    %
    % Users must contact us to obtain a separate agreement before using
    % the Software for commercial purposes. Commercial use includes,
    % but is not limited to, incorporating the Software into a product
    % for sale or distribution, or using the Software to provide services
    % or support for a commercial entity.
    %
    % Email: km834@cam.ac.uk, smg84@cam.ac.uk;
    %
    properties (Access = private)
        % table file parameters
        filePath % store the selected file path
        tableFile % store the loaded table content
        tableVariableNames % store the variable names (column) of the loaded table
        fileLoaded = false % indicator if the file is loaded

        % normalisation parameter
        normalised = false % indicator if the tableFile is normalised

        % introduction mode
        introductionMode = false % indicator if the app is in introduction mode
        introductionStep = 1 % which step the app is

        % define class functions
        curveModelClass % the curve model calibration class
        figureOperationClass % create the figure operation class
        tableOperationClass % create a table operation class

        % sub-app dialog
        saveOptionApp % save option app
    end

    %% allow other app to access
    properties (Access = public)
        optimisedTable % this table has the optimised parameters and optimisation summary
        finishedFlag = false % flag to indicate if the optimisation is successfully finished
    end

    %%
    methods (Access = private)

        %%
        function runIntroductionSection(app)
            % disable all buttons/dropdown/checkbox/editfield, except 'Select' button
            switch app.introductionStep
                case 1 % step1: only enable the select button
                    controlUnitsAbility(app, 'off');
                    app.SelectButton.Enable = 'on';
                    message = 'Please click Select buttion to select files.';
                    uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Step 1', 'Icon', 'info');
                case 2 % step2: select x-/y- axis and load file
                    message = 'Please select the x- and y-axes data, and click Load button';
                    uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Step 2', 'Icon', 'info');
                    app.XaxisDropDown.Enable = 'on';
                    app.YaxisDropDown.Enable = 'on';
                    app.NormaliseXaxisdependingonthemaximumxCheckBox.Enable = 'on';
                    app.LoadButton.Enable = 'on';
                case 3 % step3: select curve model and optimisation method
                    message = 'Please select a curve model and a optimisation method, and then click Run button to run the calibration.';
                    uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Step 3', 'Icon', 'info');
                    app.CurveModelDropDown.Enable = 'on';
                    app.VxvariabilityalongxaxisStimulusStrengthCheckBox.Enable = 'on';
                    app.OptimisationMethodDropDown.Enable = 'on';
                    app.MaxFunEvalsEditField.Enable = 'on';
                    app.TolFunEditField.Enable = 'on';
                    app.MaxIterEditField.Enable = 'on';
                    app.MaxFunEvalsEditField.Enable = 'on';
                    app.stopTolerance.Enable = 'on';
                    app.RunButton.Enable = 'on';
                case 4 % step 4: save the optimisation results
                    message = 'After optimisation, you can view the results in Figures panel and click the Save Results to save your optimised results.';
                    uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Step 4', 'Icon', 'info');
                    app.SaveResultsButton.Enable = 'on';
                case 5 % step 5: how to save
                    message = 'You can select files you want to save and click the drop-down list after the figure names to select the preferred format. Enjoy!';
                    uialert(app.saveOptionApp.saveOptionUIFigure, message, 'Step 5', 'Icon', 'info');
                    app.introductionMode = false;
                otherwise
                    % do nothing
            end


        end

        %% control the ability of units
        function controlUnitsAbility(app, state)
            % find units: button, dropdown list, checkbox, editfield;
            % state is off or on
            unitNames = {'dropdown', 'button', 'checkbox', 'editfield'};
            propNames = properties(app);
            for i = 1:length(propNames)
                try
                    if contains(get(app.(propNames{i}), 'Type'), unitNames, 'IgnoreCase', true)
                        app.(propNames{i}).Enable = state;
                    end
                catch
                    % do nothing
                end
            end
        end


        %% plot figures
        function plotFigures(app)
            % get the parameters
            myModel = app.curveModelClass.selectedCurveModelValue;
            myMLParameters = app.curveModelClass.opti_parameters_maximumlikelihood;

            % plot figures
            % Fig 1: MEP IO curve with variability,
            % maximum-likelihood method
            app.figureOperationClass.plotMEPIOCurveVariability(app.UIAxes_VariabilityMEPCurve, myMLParameters, myModel,...
                app.curveModelClass.vx_selected);

            % Fig 3 and Fig 4: Excitability level (only availiable when vx is selected)
            if app.VxvariabilityalongxaxisStimulusStrengthCheckBox.Value
                app.figureOperationClass.plotExcitabilityLevel(app.UIAxes_excitabilityLevel, app.UIAxes_excitabilityPvalue, myMLParameters, myModel);
            else
                cla(app.UIAxes_excitabilityLevel);
                cla(app.UIAxes_excitabilityPvalue);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % find the current working path
            functionPath = fullfile(pwd, 'Functions');
            addpath(functionPath)

            % set up basic classes
            app.curveModelClass = modelCalibration;
            app.figureOperationClass = figureOperation;
            app.tableOperationClass = tableOperation;

            % default for normalisation
            app.MaxXaxisEditField.Enable = "off";
            app.MaxXaxisEditField.Editable = 'off';

            % default for model, y-axis variability must included
            app.VyvariabilityalongyaxisMEPCheckBox.Enable = 'off';
            app.VyvariabilityalongyaxisMEPCheckBox.Value = 1;

            % initialise the drop-down list for model calibration
            app.CurveModelDropDown.Items = app.curveModelClass.curveModelList;
            app.OptimisationMethodDropDown.Items = app.curveModelClass.optimisationMethodList;

            % default curve model and optimisation method
            app.curveModelClass.selectedOptimisationMethodValue = app.OptimisationMethodDropDown.Value;
            app.curveModelClass.selectedCurveModelValue = app.CurveModelDropDown.Value;

            % model form text
            app.ModelFormTextArea.Value = app.curveModelClass.printCurveModel();

            % initialise the optimisation parameters
            app.TolFunEditField.Value = app.curveModelClass.functionTolerance;
            app.stopTolerance.Value = app.curveModelClass.stopTolerance;
            app.MaxFunEvalsEditField.Value = app.curveModelClass.maximumFunctionValue;
            app.MaxIterEditField.Value = app.curveModelClass.maximumIteration;

            % curve model class default value
            app.curveModelClass.vx_selected = app.VxvariabilityalongxaxisStimulusStrengthCheckBox.Value;

            % initialise figure legend and titles
            app.figureOperationClass.initialiseFigures(app.UIAxes_VariabilityMEPCurve,...
                app.UIAxes_excitabilityLevel, app.UIAxes_excitabilityPvalue);

            % initialise optimisation summary table with only row names
            app.optiParameterTable.Data = app.curveModelClass.summariseOptiTable(app.finishedFlag);

            % start a usage introduction
            message = "Do you want a quick tutorial?";
            answer = uiconfirm(app.MEPIOCurveAnalyserUIFigure, message, 'Tutorial',...
                'Options', ["Yes", "No"], 'DefaultOption', 2);
            switch answer
                case 'Yes'
                    app.runIntroductionSection();
                    app.introductionMode = true; % the app is in the introduction mode
                otherwise
                    % do nothing
                    app.introductionMode = false;
            end
        end

        % Button pushed function: SelectButton
        function selectFile(app, event)
            app.fileLoaded = false;
            % File selector: only available to files, .xlsx and .csv
            [fileName, fileDic] = uigetfile({'All supported files;*.xlsx;*.csv;*.xls;*.xlsb;*.xlsm;*.xlsx;*.xltm;*.xltx;*.ods';...
                '*.xlsx'; '*.csv'; '*.xls'; '*.xlsb'; '*.xlsm'; '*.xltm'; '*.xltx'; '*.ods'}, 'Select a File');

            % if a file is selected
            if not(isequal(fileName, 0))
                % find the file path
                app.filePath = fullfile(fileDic, fileName); % the entire file path
                % set the textfield
                app.FilePathTextArea.Value = fileName;
                %
                app.tableFile = readtable(app.filePath); % read table
                app.tableVariableNames = [{'None'}, app.tableFile.Properties.VariableNames]; % get the table column names
                % Load the variable names to both drop-down lists
                % Change the default values
                app.XaxisDropDown.Items = app.tableVariableNames;
                app.YaxisDropDown.Items = app.tableVariableNames;

                % if the app is in introduction mode
                if app.introductionMode == true
                    app.introductionStep = 2;
                    app.runIntroductionSection()
                end
            end
        end

        % Value changed function: XaxisDropDown
        function selectDataColumn_X(app, event)
            % load the current selected value
            app.fileLoaded = false;
            value_x = app.XaxisDropDown.Value;
            value_y = app.YaxisDropDown.Value;
            if strcmp(value_x, value_y) && ~strcmp(value_x, 'None')
                message = [value_x, ' is selected by y-axis. Please select another variable.'];
                uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Warning', 'Icon', 'warning');
                app.XaxisDropDown.Value = 'None';
            end
            % find the maximum value
            if ~strcmp(value_x, 'None')
                app.MaxXaxisEditField.Value = max(app.tableFile.(value_x));
            else
                app.MaxXaxisEditField.Value = 0;
            end

        end

        % Value changed function: YaxisDropDown
        function selectDataColumn_Y(app, event)
            app.fileLoaded = false;
            value_x = app.XaxisDropDown.Value;
            value_y = app.YaxisDropDown.Value;
            if strcmp(value_x, value_y) && ~strcmp(value_y, 'None')
                message = [value_y, ' is selected by x-axis. Please select another variable.'];
                uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Warning', 'Icon', 'warning');
                app.YaxisDropDown.Value = 'None';
            end
        end

        % Value changed function: 
        % NormaliseXaxisdependingonthemaximumxCheckBox
        function normSelection(app, event)
            app.fileLoaded = false;
            value = app.NormaliseXaxisdependingonthemaximumxCheckBox.Value;
            if value
                app.MaxXaxisEditField.Enable = 'on';
                app.MaxXaxisEditField.Editable = 'on';
            else
                app.MaxXaxisEditField.Enable = "off";
                app.MaxXaxisEditField.Editable = 'off';
            end
        end

        % Value changed function: MaxXaxisEditField
        function maxNormSelection(app, event)
            app.fileLoaded = false;
        end

        % Button pushed function: LoadButton
        function loadFileandNorm(app, event)
            value_CheckBox = app.NormaliseXaxisdependingonthemaximumxCheckBox.Value;
            fileValue = app.FilePathTextArea.Value;
            value_xaxis = app.XaxisDropDown.Value;
            value_yaxis = app.YaxisDropDown.Value;

            %
            if ~strcmp(fileValue, 'None') % check if file is selected
                % load table
                app.tableOperationClass.loadData(app.filePath); % load the original table
                app.tableFile = app.tableOperationClass.origTableFile;

                if ~strcmp(value_xaxis, 'None') && ~strcmp(value_yaxis, 'None') % make sure each column is selected
                    % normalise the data: x-axis
                    if value_CheckBox % if normalisation is on
                        app.tableFile = app.tableOperationClass.normaliseAxis(app.tableFile, value_xaxis, app.MaxXaxisEditField.Value);
                    else % if normalisation is off
                        app.tableFile = app.tableOperationClass.origTableFile;
                    end

                    % log-transforme the data: y-axis
                    app.tableFile = app.tableOperationClass.logTransformedAxis(app.tableFile, value_yaxis);

                    % load file to modelCurveClass
                    app.curveModelClass.loadData(app.tableFile.(value_xaxis), app.tableFile.(value_yaxis));
                    app.figureOperationClass.loadData(app.tableFile.(value_xaxis), app.tableFile.(value_yaxis));
                    app.fileLoaded = true; % the file is successfully loaded
                    message = "File is loaded!";
                    uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Message', 'Icon', 'success');

                    % update table
                    app.optimisedTable = app.curveModelClass.summariseOptiTable(app.finishedFlag);
                    tableOperation.updateUItable(app.optiParameterTable, app.optimisedTable, app.curveModelClass.selectedCurveModelValue);

                    % if the app is in introduction mode
                    if app.introductionMode == true
                        app.introductionStep = 3;
                        app.runIntroductionSection();
                    end

                else
                    message = 'x- or y-axis is not selected!';
                    uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Warning', 'Icon', 'warning');
                    app.fileLoaded = false;
                end
            else
                message = 'File is not selected!';
                uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Warning', 'Icon', 'warning');
                app.fileLoaded = false;
            end
        end

        % Value changed function: CurveModelDropDown, 
        % ...and 7 other components
        function optimisationSelectFunction(app, event)
            % change the states of vx_selected
            app.curveModelClass.vx_selected = app.VxvariabilityalongxaxisStimulusStrengthCheckBox.Value;

            % curve model selection
            app.curveModelClass.selectedCurveModelValue = app.CurveModelDropDown.Value;
            app.ModelFormTextArea.Value = app.curveModelClass.printCurveModel();

            % optimisation method selection
            app.curveModelClass.selectedOptimisationMethodValue = app.OptimisationMethodDropDown.Value;

            % maximum iteration
            app.curveModelClass.maximumIteration = app.MaxIterEditField.Value;

            % function tolerance
            app.curveModelClass.functionTolerance = app.TolFunEditField.Value;

            % maximum function evaluation
            app.curveModelClass.maximumFunctionValue = app.MaxFunEvalsEditField.Value;

            % stop tolerance
            app.curveModelClass.stopTolerance = app.stopTolerance.Value;

            % update table
            app.optimisedTable = app.curveModelClass.summariseOptiTable(app.finishedFlag);
            tableOperation.updateUItable(app.optiParameterTable, app.optimisedTable, app.curveModelClass.selectedCurveModelValue);
        end

        % Button pushed function: RunButton
        function runCalibration(app, event)
            % check if file state is changed
            if app.fileLoaded == true
                % check all optimisation settings first (cannot be zero)
                if app.curveModelClass.checkParameters() == true
                    % create a processing bar
                    dlg = uiprogressdlg(app.MEPIOCurveAnalyserUIFigure, 'Title', 'Solving calibration',...
                        'Indeterminate', 'on', 'Cancelable', 'on');
                    drawnow

                    % attach a process dialog
                    app.curveModelClass.attachDialog(dlg);

                    % do calibration and update the parameter table
                    try
                        app.curveModelClass.runCalibration();

                        % set the unfinishedFlag true
                        app.finishedFlag = true;

                        % update table
                        app.optimisedTable = app.curveModelClass.summariseOptiTable(app.finishedFlag);
                        tableOperation.updateUItable(app.optiParameterTable, app.optimisedTable, app.curveModelClass.selectedCurveModelValue);

                        % plot figures
                        app.plotFigures();

                    catch ME
                        % catch stop signal
                        disp(ME.message)
                        if strcmp(ME.message, 'Stop Optimisation')
                            app.finishedFlag = false;
                        end
                    end

                    % close pending dialog
                    close(dlg)

                    % introduction model step 4
                    if (app.introductionMode == true) && (app.finishedFlag == true)
                        app.introductionStep = 4;
                        app.runIntroductionSection();
                    end

                else
                    message = "Optimisation parameters cannot be zero!";
                    uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Warning', 'Icon', 'warning');
                end
            else
                message = "File is not successfully loaded! Make sure press the Load button after making any changes.";
                uialert(app.MEPIOCurveAnalyserUIFigure, message, 'Warning', 'Icon', 'warning');
            end
        end

        % Button pushed function: SaveResultsButton
        function saveOptions(app, event)
            % Disable Save Option button while dialog is open
            app.SaveResultsButton.Enable = "off";

            % Call save option dialog with inputs
            app.saveOptionApp = saveDialog(app);

            % if the app is in introduction mode
            if app.introductionMode == true
                app.introductionStep = 5;
                app.runIntroductionSection();
            end

        end

        % Menu selected function: AboutusMenu
        function informationShow(app, event)
            message = {'MEP IO Curve Analyser (Version 1.0)'; '';
                'Authors: Ke Ma, Stephan M. Goetz.'; '';
                'Address: Electrical Engineering Divison,';
                '         Department of Engineering,';
                '         University of Cambridge.';
                'Email:   km834@cam.ac.uk; smg84@cam.ac.uk.';
                ' ';
                ' ';
                'This app is only for non-commercial use.';
                'Users should contact us for commerical use.'};
            uialert(app.MEPIOCurveAnalyserUIFigure, message, 'About us', 'Icon', 'info', 'Interpreter', 'latex');
        end

        % Close request function: MEPIOCurveAnalyserUIFigure
        function MEPIOCurveAnalyserUIFigureCloseRequest(app, event)
            % Delete pop-up window
            try
                delete(app.saveOptionApp)
            catch
                % do nothing
            end
            % close main app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MEPIOCurveAnalyserUIFigure = uifigure('Visible', 'off');
            app.MEPIOCurveAnalyserUIFigure.Name = 'MEP IO Curve Analyser';
            app.MEPIOCurveAnalyserUIFigure.CloseRequestFcn = createCallbackFcn(app, @MEPIOCurveAnalyserUIFigureCloseRequest, true);
            app.InformationMenu = uimenu(app.MEPIOCurveAnalyserUIFigure);
            app.InformationMenu.Text = 'Information';
            app.AboutusMenu = uimenu(app.InformationMenu);
            app.AboutusMenu.MenuSelectedFcn = createCallbackFcn(app, @informationShow, true);
            app.AboutusMenu.Text = 'About us';
            app.GridLayout4 = uigridlayout(app.MEPIOCurveAnalyserUIFigure);
            app.FileSelectionPanel = uipanel(app.GridLayout4);
            app.FileSelectionPanel.Title = 'File Selection';
            app.GridLayout = uigridlayout(app.FileSelectionPanel);
            app.FilePathTextAreaLabel = uilabel(app.GridLayout);
            app.FilePathTextAreaLabel.Text = 'File Path:';
            app.FilePathTextArea = uitextarea(app.GridLayout);
            app.FilePathTextArea.Editable = 'off';
            app.FilePathTextArea.Value = {'None'};
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.SelectButton.Tooltip = {'Please select a file.'};
            app.SelectButton.Text = 'Select';
            app.XaxisDropDownLabel = uilabel(app.GridLayout);
            app.XaxisDropDownLabel.Text = 'X-axis:';
            app.XaxisDropDown = uidropdown(app.GridLayout);
            app.XaxisDropDown.Items = {'None'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @selectDataColumn_X, true);
            app.XaxisDropDown.Tooltip = {'Select x-axis value.'};
            app.XaxisDropDown.Value = 'None';
            app.MaxXaxisEditFieldLabel = uilabel(app.GridLayout);
            app.MaxXaxisEditFieldLabel.Text = 'Max(X-axis):';
            app.MaxXaxisEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxXaxisEditField.Limits = [0 Inf];
            app.MaxXaxisEditField.ValueChangedFcn = createCallbackFcn(app, @maxNormSelection, true);
            app.MaxXaxisEditField.Editable = 'off';
            app.MaxXaxisEditField.Tooltip = {'The maximum value in the selected X-axis. This can be modified for later normalisation.'};
            app.NormaliseXaxisdependingonthemaximumxCheckBox = uicheckbox(app.GridLayout);
            app.NormaliseXaxisdependingonthemaximumxCheckBox.ValueChangedFcn = createCallbackFcn(app, @normSelection, true);
            app.NormaliseXaxisdependingonthemaximumxCheckBox.Tooltip = {'Normalise the range of the x-axis down to 0-100.'};
            app.NormaliseXaxisdependingonthemaximumxCheckBox.Text = 'Normalise X-axis depending on the maximum x.';
            app.YaxisDropDownLabel = uilabel(app.GridLayout);
            app.YaxisDropDownLabel.Text = 'Y-axis:';
            app.YaxisDropDown = uidropdown(app.GridLayout);
            app.YaxisDropDown.Items = {'None'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @selectDataColumn_Y, true);
            app.YaxisDropDown.Tooltip = {'Select y-axis value.'};
            app.YaxisDropDown.Value = 'None';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadFileandNorm, true);
            app.LoadButton.Tooltip = {'Load the selected file.'};
            app.LoadButton.Text = 'Load';
            app.ResultsPanel = uipanel(app.GridLayout4);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout5 = uigridlayout(app.ResultsPanel);
            app.UIAxes_VariabilityMEPCurve = uiaxes(app.GridLayout5);
            zlabel(app.UIAxes_VariabilityMEPCurve, 'Z')
            app.UIAxes_excitabilityLevel = uiaxes(app.GridLayout5);
            zlabel(app.UIAxes_excitabilityLevel, 'Z')
            app.UIAxes_excitabilityPvalue = uiaxes(app.GridLayout5);
            zlabel(app.UIAxes_excitabilityPvalue, 'Z')
            app.SaveResultsButton = uibutton(app.GridLayout5, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @saveOptions, true);
            app.SaveResultsButton.Tooltip = {'Save the figures and optimised results.'};
            app.SaveResultsButton.Text = 'Save Results';
            app.optiParameterTable = uitable(app.GridLayout5);
            app.optiParameterTable.ColumnName = {'Name'; 'Value'};
            app.optiParameterTable.ColumnRearrangeable = 'on';
            app.optiParameterTable.RowName = {};
            app.optiParameterTable.Tooltip = {'This table shows the optimised model parameters.'};
            app.ModelSelectionPanel = uipanel(app.GridLayout4);
            app.ModelSelectionPanel.Title = 'Model Selection';
            app.GridLayout2 = uigridlayout(app.ModelSelectionPanel);
            app.CurveModelDropDownLabel = uilabel(app.GridLayout2);
            app.CurveModelDropDownLabel.Text = 'Curve Model:';
            app.CurveModelDropDown = uidropdown(app.GridLayout2);
            app.CurveModelDropDown.Items = {'None'};
            app.CurveModelDropDown.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.CurveModelDropDown.Tooltip = {'Select a curve model.'};
            app.CurveModelDropDown.Value = 'None';
            app.VariabilityParameterSelectionLabel = uilabel(app.GridLayout2);
            app.VariabilityParameterSelectionLabel.Tooltip = {'Add the variability sources to x- or y-axis.'};
            app.VariabilityParameterSelectionLabel.Text = 'Variability Parameter Selection:';
            app.VxvariabilityalongxaxisStimulusStrengthCheckBox = uicheckbox(app.GridLayout2);
            app.VxvariabilityalongxaxisStimulusStrengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.VxvariabilityalongxaxisStimulusStrengthCheckBox.Tooltip = {'Add the variability sources to x- or y-axis.'};
            app.VxvariabilityalongxaxisStimulusStrengthCheckBox.Text = 'Vx - variability along x-axis (Stimulus Strength).';
            app.VxvariabilityalongxaxisStimulusStrengthCheckBox.Value = true;
            app.VyvariabilityalongyaxisMEPCheckBox = uicheckbox(app.GridLayout2);
            app.VyvariabilityalongyaxisMEPCheckBox.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.VyvariabilityalongyaxisMEPCheckBox.Tooltip = {'Add the variability sources to x- or y-axis.'};
            app.VyvariabilityalongyaxisMEPCheckBox.Text = 'Vy - variability along y-axis (MEP).';
            app.VyvariabilityalongyaxisMEPCheckBox.Value = true;
            app.ModelFormTextAreaLabel = uilabel(app.GridLayout2);
            app.ModelFormTextAreaLabel.Text = 'Model Form:';
            app.ModelFormTextArea = uitextarea(app.GridLayout2);
            app.ModelFormTextArea.Editable = 'off';
            app.OptimisationMethodDropDownLabel = uilabel(app.GridLayout2);
            app.OptimisationMethodDropDownLabel.Text = 'Optimisation Method:';
            app.OptimisationMethodDropDown = uidropdown(app.GridLayout2);
            app.OptimisationMethodDropDown.Items = {'None'};
            app.OptimisationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.OptimisationMethodDropDown.Tooltip = {'Select a optimisation algorithm.'};
            app.OptimisationMethodDropDown.Value = 'None';
            app.MaximumIteration_Label = uilabel(app.GridLayout2);
            app.MaximumIteration_Label.Text = 'Maximum Iteration:';
            app.MaxIterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxIterEditField.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.MaxIterEditField.Tooltip = {'Maximum iteration of the chosen optimisation algorithm.'};
            app.FunctionTolerance_Label = uilabel(app.GridLayout2);
            app.FunctionTolerance_Label.Text = 'Function Tolerance:';
            app.TolFunEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TolFunEditField.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.TolFunEditField.Tooltip = {'The termination tolerance on function value.'};
            app.MaxFunEvalsEditField_Label = uilabel(app.GridLayout2);
            app.MaxFunEvalsEditField_Label.Text = 'Maximum Evaluation:';
            app.MaxFunEvalsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxFunEvalsEditField.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.MaxFunEvalsEditField.Tooltip = {'The maximum number of function evaluations.'};
            app.stopTolerance_Label = uilabel(app.GridLayout2);
            app.stopTolerance_Label.Text = 'Stop Tolerance:';
            app.stopTolerance = uieditfield(app.GridLayout2, 'numeric');
            app.stopTolerance.ValueChangedFcn = createCallbackFcn(app, @optimisationSelectFunction, true);
            app.stopTolerance.Tooltip = {'Termination tolerance on x, the current point.'};
            app.RunButton = uibutton(app.GridLayout2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runCalibration, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.MEPIOCurveAnalyserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEP_IO_Model_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MEPIOCurveAnalyserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MEPIOCurveAnalyserUIFigure)
        end
    end
end